classdef LiveAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        YlimEditField                matlab.ui.control.NumericEditField
        YlimEditFieldLabel           matlab.ui.control.Label
        HoldLabel                    matlab.ui.control.Label
        HoldFR                       matlab.ui.control.CheckBox
        HoldSacc                     matlab.ui.control.CheckBox
        MaxfiringrateEditField       matlab.ui.control.NumericEditField
        MaxfiringrateEditFieldLabel  matlab.ui.control.Label
        SpikechannelSpinner          matlab.ui.control.Spinner
        SpikechannelLabel            matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        SaccadesTab                  matlab.ui.container.Tab
        AlignstateSpinner            matlab.ui.control.Spinner
        AlignstateSpinnerLabel       matlab.ui.control.Label
        ylimEditField                matlab.ui.control.EditField
        ylimEditFieldLabel           matlab.ui.control.Label
        xlimEditField                matlab.ui.control.EditField
        xlimEditFieldLabel           matlab.ui.control.Label
        HoldacrosstrialsCheckBox     matlab.ui.control.CheckBox
        XY                           matlab.ui.control.UIAxes
        CustomTab                    matlab.ui.container.Tab
        HeatmapTab                   matlab.ui.container.Tab
        Spikes_YT                    matlab.ui.control.UIAxes
        YT                           matlab.ui.control.UIAxes
    end


    properties (Access = public)
        started=0;
        keep_plotting=1;
    end

    properties (Access = private)
        ylim;
        xlim;
        biglimrange;
        midsaccade=0;
        toplot=0;
        statenum=0;
        prevstate=0;
        eye;
        trialstart=0;
    end

    methods (Access = private)
        function idle_loop(app)
            while app.trialstart
                isspike=xippmex('spike', app.SpikechannelSpinner.Value,0);
                timer=[];
                app.started = 0;

                xysaccade=pix2deg(app.eye.geteye(100)');
                eyeposx=xysaccade(1,:);
                eyeposy=xysaccade(2,:);
                setlims(app)

                if app.statenum == app.AlignstateSpinner.Value && app.started == 0
                    timer = getsecs;
                    app.started=1;
                end
                timestamp=ceil((getsecs-timer)*1000);               

                plot(app.YT,(timestamp-99):timestamp,eyeposx,'r');
                hold(app.YT,'on')
                plot(app.YT,(timestamp-99):timestamp,eyeposy,'.b');
                [saccade_status, ts]=app.detectsaccade(eyeposx(end-20:end),eyeposy(end-20:end));

                if saccade_status == 1
                    plot(app.YT,[timestamp-ts timestamp-ts],[-90 90],'k','LineWidth',5)
                    app.setlims;
                elseif saccade_status == 2
                    plot(app.YT,[timestamp-ts timestamp-ts],[-90 90],'k','LineWidth',1)
                    app.setlims;
                end 

                if app.statenum~=app.prevstate
                    plot(app.YT,[timestamp timestamp],[-90 90],'g','LineWidth',1)
                    app.setlims;
                end

                if isspike
                    plot(app.Spikes_YT,[timestamp timestamp],[-90 90],'k','LineWidth',1)
                    app.setlims;
                end

                drawnow
            end
            cla(app.YT)
            if ~app.HoldacrosstrialsCheckBox.Value
                cla(app.XY)
            end
        end

        function [saccade_status, ts]=detectsaccade(app,eyeposx,eyeposy)
            idx=1;
            saccade_status=0;
            saccend=0;

            saccx=nan(1,length(eyeposx));
            saccy=nan(1,length(eyeposy));

            if length(eyeposx)>19
                velindex=abs(...
                    smooth(...
                    diff(...
                    hypot(...
                    eyeposx(end-20:end),eyeposy(end-19:end)...
                    )...
                    )...
                    ,5)*1000 ...
                    )>30;
            else
                velindex=zeros(1,20);
            end

            if sum(velindex>0)>5 && ~app.midsaccade
                app.midsaccade=1;
                app.toplot=1;
                saccade_status=1;
                ts=20-find(velindex==1,1);
                plotpositiony=saccy(find(velindex==1,1):end);
                plotpositionx=saccx(find(velindex==1,1):end);
            end

            if ~any(velindex(end-5:end)) && app.midsaccade
                app.midsaccade=0;
                app.toplot=1;
                saccade_status=2;
                ts=15;
                saccend=1;
            end

            if app.toplot && idx>0
                hold(app.XY,'on')
                plot(app.XY,plotpositionx,plotpositiony,'.k','LineWidth',0.2,'MarkerSize',0.1)
                if saccend
                    scatter(app.XY,plotpositionx(end),plotpositiony(end),20,'r','filled')
                end
                app.toplot=0;
            end

        end

        function setlims(app)
            app.xlim=str2num(app.xlimEditField.Value);
            app.ylim=str2num(app.ylimEditField.Value);

            app.XY.XLim=app.xlim;
            app.XY.YLim=app.ylim;

            %%find min and max
            if app.xlim(1)<app.ylim(1)
                app.biglimrange(1)=app.xlim(1);
            else
                app.biglimrange(1)=app.ylim(1);
            end
            if app.xlim(2)>app.ylim(2)
                app.biglimrange(2)=app.xlim(2);
            else
                app.biglimrange(2)=app.ylim(2);
            end

            app.YT.XLim=[-100 app.YlimEditField.Value];
            app.YT.YLim=app.biglimrange;

            app.Spikes_YT.XLim=[-100 app.YlimEditField.Value];
            app.Spikes_YT.YLim=[0 app.MaxfiringrateEditField.Value];
        end

        function getCommands(app,rewardport,~)
            app.statenum=0;
            app.trialstart=0;
            command=readline(rewardport);
            if strcmp(command,'trialstart')
                app.trialstart=1;
                app.eye=eyeinfo;
            elseif strcmp(command,'trialend')
                app.trialstart=0;
            else
                app.prevstate = app.statenum;
                app.statenum = app.statenum+1;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setlims
            set(app.YT,'xaxisLocation','top')

            system('gnome-terminal --title="Live Analysis"');
            liveanalysisport = udpport("LocalPort",2023);
            configureCallback(liveanalysisport,"terminator",@getCommands);
            xippmex;
            system('clear');
            disp('-----Live Analysis-----');

            app.idle_loop
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.YT = uiaxes(app.UIFigure);
            title(app.YT, 'eye position')
            zlabel(app.YT, 'Z')
            app.YT.XLim = [0 5];
            app.Spikes_YT = uiaxes(app.UIFigure);
            xlabel(app.Spikes_YT, 'Time (ms)')
            zlabel(app.Spikes_YT, 'Z')
            app.Spikes_YT.XLim = [0 5];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SaccadesTab = uitab(app.TabGroup);
            app.SaccadesTab.Title = 'Saccades';
            app.XY = uiaxes(app.SaccadesTab);
            zlabel(app.XY, 'Z')
            app.XY.XLim = [-30 30];
            app.XY.YLim = [-30 30];
            app.HoldacrosstrialsCheckBox = uicheckbox(app.SaccadesTab);
            app.HoldacrosstrialsCheckBox.Text = 'Hold across trials';
            app.HoldacrosstrialsCheckBox.Value = true;
            app.xlimEditFieldLabel = uilabel(app.SaccadesTab);
            app.xlimEditFieldLabel.Text = 'xlim';
            app.xlimEditField = uieditfield(app.SaccadesTab, 'text');
            app.xlimEditField.Value = '[-30 30]';
            app.ylimEditFieldLabel = uilabel(app.SaccadesTab);
            app.ylimEditFieldLabel.Text = 'ylim';
            app.ylimEditField = uieditfield(app.SaccadesTab, 'text');
            app.ylimEditField.Value = '[-20 20]';
            app.AlignstateSpinnerLabel = uilabel(app.SaccadesTab);
            app.AlignstateSpinnerLabel.Text = 'Align state';
            app.AlignstateSpinner = uispinner(app.SaccadesTab);
            app.CustomTab = uitab(app.TabGroup);
            app.CustomTab.Title = 'Custom';
            app.HeatmapTab = uitab(app.TabGroup);
            app.HeatmapTab.Title = 'Heatmap';
            app.SpikechannelLabel = uilabel(app.UIFigure);
            app.SpikechannelLabel.Text = 'Spike channel #';
            app.SpikechannelSpinner = uispinner(app.UIFigure);
            app.SpikechannelSpinner.Value = 1;
            app.MaxfiringrateEditFieldLabel = uilabel(app.UIFigure);
            app.MaxfiringrateEditFieldLabel.Text = 'Max firing rate';
            app.MaxfiringrateEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxfiringrateEditField.Editable = 'off';
            app.MaxfiringrateEditField.Value = 1;
            app.HoldSacc = uicheckbox(app.UIFigure);
            app.HoldSacc.Text = '';
            app.HoldFR = uicheckbox(app.UIFigure);
            app.HoldFR.Text = '';
            app.HoldLabel = uilabel(app.UIFigure);
            app.HoldLabel.Text = 'Hold';
            app.YlimEditFieldLabel = uilabel(app.UIFigure);
            app.YlimEditFieldLabel.Text = 'Ylim';
            app.YlimEditField = uieditfield(app.UIFigure, 'numeric');
            app.YlimEditField.Value = 2000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LiveAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end